classdef rLott_Dollar_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        uitoolbar1                  matlab.ui.container.Toolbar
        uipushtool1                 matlab.ui.container.toolbar.PushTool
        uipushtool2                 matlab.ui.container.toolbar.PushTool
        WhiteCenterLabel            matlab.ui.control.Label
        RedEndPointLabel            matlab.ui.control.Label
        GreenStartPointLabel        matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        SelectclasstodisplayonrightLabel  matlab.ui.control.Label
        examplePlot                 matlab.ui.control.Image
        displayText                 matlab.ui.control.TextArea
        ClassesListBox              matlab.ui.control.ListBox
        ClassesListBoxLabel         matlab.ui.control.Label
        overwriteBox                matlab.ui.control.CheckBox
        nameText                    matlab.ui.control.EditField
        nameTextLabel               matlab.ui.control.Label
        confirmButton               matlab.ui.control.Button
        MakeNewTemplateSwitch       matlab.ui.control.Switch
        MakeNewTemplateSwitchLabel  matlab.ui.control.Label
        classifyButton              matlab.ui.control.Button
        clearButton                 matlab.ui.control.Button
        drawGraph                   matlab.ui.control.UIAxes
        ContextMenu2                matlab.ui.container.ContextMenu
        ClearMenu                   matlab.ui.container.Menu
        ClassifyMenu                matlab.ui.container.Menu
        MakeATemplateMenu           matlab.ui.container.Menu
        ResetMenu                   matlab.ui.container.Menu
        ContextMenu3                matlab.ui.container.ContextMenu
        DeleteItemMenu              matlab.ui.container.Menu
    end


%%%Modified and written by Robert Lott 
% Assignment due 3/20/2022
% Dollar Classifier 
% Makes use of a drawnObj class that generates for both templates and
% objects as well as several save files. 
    methods (Access = private)
        function createTemplateFiles(app)
            %             Creates the raw template files for
            %             initialization. from pre-made data, this is
            %             unused here. 
            dataIDs  = loadTemplateDict(app);
            dataNums = [0:9];
            templateHolder = drawnObj.empty(10,0);
            for i = dataNums
                fileName = ['digit_',num2str(i),'_',num2str(1),'.mat'];
                fileStruct = load(fileName);
                pointsVar  = fileStruct.points;
                template = drawnObj(0,pointsVar,string(dataIDs(i+1)));
                templateHolder(i+1) = template;
                saveStruct = saveobj(app, template);
                fileName = 'template_' + string(dataIDs(i+1)) + '.mat'    ;
                save(fileName, 'saveStruct');
            end
        end
        function createNewTemplate(app, newID)
%             generaetes a brand new template from points
            global points;
            global pID;
            global templateObjects;
            dataIDs  = loadTemplateDict(app);
            %             IDs = length(dataIDs)
            %             fileName = ['template_',newID,'.mat'];
            templateID = newID;
%             create the file name
            fileName = ['template_' , templateID , '.mat'];
            fileNum = 0;
            isItNew = true;
%             check if the template is actually new or not
            for i = 1:length(dataIDs)
                if strcmpi((string(dataIDs(i))),(newID))
                    isItNew = false;
                end
            end
%             if it is, or if you're interested in making a new file
%             anyway, go into here 
            if(~app.overwriteBox.Value || isItNew)
                while(isfile(fileName))
%                     this will check if the file already exists, if it
%                     does it'll make a new name for it and ID to match
                    fileNum = fileNum +1;
                    templateID = [newID , num2str(fileNum)];
                    fileName = ['template_' , templateID , '.mat'];
                end
%                 once the file name is determined, data will be written
%                 and updated in templateDict.csv and the existing
%                 app.
                dataIDs{end+1} = templateID;
                writecell(dataIDs,'templateDict.csv');
%                 after which, the template itself will be created, turned
%                 into a struct, and saved
                pointsVar  = points(1:pID,:);
                template = drawnObj(0,pointsVar,templateID);
                saveStruct = saveobj(app, template);
                save(fileName, 'saveStruct');
%                 and of course, added back into the environment so you
%                 don't need to reset 
                templateObjects = [templateObjects; template];
                %                   templateHolder = [templateHolder, template];
                app.ClassesListBox.Items{end+1} = templateID;
                return
            end
            %             otherwise, a template will simply be made
            %             using the information provided and a struct will
            %             be designed with the assumption that the template
            %             both doesn't already exist and you would prefer
            %             not to make a duplicate.
            disp("template holder is: ");
            disp(length(templateObjects));
            pointsVar  = points(1:pID,:);
            template = drawnObj(0,pointsVar,templateID);
            saveStruct = saveobj(app, template);
            save(fileName, 'saveStruct');

            for i = 1: length(templateObjects)
                if (templateObjects(i).groundTruth == templateID)
                    templateObjects(i) = template;
                    return
                end
            end
        end

        function dataIDs = loadTemplateDict(app)
%             loads from csv, i did this because i kept forgetting what the
%             file was
            dataIDs = readcell('templateDict.csv')
            dataIDs = dataIDs(:)
        end
        function loadTemplates(app)
%             general load templates for app start
            global templateObjects;
            dataIDs  = loadTemplateDict(app)
            app.ClassesListBox.Items = string(dataIDs');
            %             createTemplateFiles(app);
            templateCount = length(dataIDs);
            dataNums = [1:templateCount];
            obj = [];
%             it will iterate through all template files based on the
%             template "dictionary" i already created. That way we don't
%             need to remember what files go where
            for i = dataNums
                fileName = 'template_' + string(dataIDs(i))
                load(fileName, 'saveStruct');
                template = drawnObj.loadobj(saveStruct);
                obj = [obj ;template];

            end
            templateObjects = obj;
        end

        function mouseDown(app, hObject, eventdata, handles)
            global ghandles;
            global points;
            global pID;
            global drawing;
            % axes(ghandles.drawGraph);

            cp = get(gca,'CurrentPoint');

            if cp(1,1)<1 && cp(1,1)>0 && cp(1,2)<1 && cp(1,2)>0
                drawing = 1;
            end
        end

        function mouseMoving(app, hObject, eventdata, handles)
            global ghandles;
            global points;
            global pID;
            global drawing;

            if drawing
                t = toc;
                cp = get(gca,'CurrentPoint');
                pID = pID + 1;
                points(pID,1) = cp(1,1);
                points(pID,2) = cp(1,2);
                points(pID,3) = t;
                plot(cp(1,1),cp(1,2),'o');

            end
        end

        function mouseUp(app, hObject, eventdata, handles)
            global ghandles;
            global points;
            global pID;
            global drawing;
            global sketchID;

            if drawing
                drawing = 0;
                sketchID = sketchID+1;
            end
            set(ghandles.clearButton,'Enable','on')
            % set(ghandles.saveButton,'Enable','on')
            set(ghandles.classifyButton,'Enable','on')
        end

        function outputClass(app, bestTemplate, Score)
            global ghandles;
            guessLen = length(Score);
            outputText = ['You drew shape: ', string(bestTemplate(1,:)),...
                ' with a score: ',num2str(Score(1),3)];
            if(guessLen > 1)
                outputText = [outputText,...
                    ' but it might be shape: ', string(bestTemplate(2,:)),...
                    ' with a score: ',num2str(Score(2),3)];
            end

            app.displayText.Value= strjoin(outputText);
            %                     set(ghandles.displayText,'string', outputText)
        end

    end
    methods (Access = public)
        function saveStruct = saveobj(app, template)
%             saves the drawn object into a struct for template matching
            %             savedObj.
            saveStruct.groundTruth = template.groundTruth
            saveStruct.ID = template.ID
            saveStruct.X = template.X
            saveStruct.Y = template.Y
            saveStruct.center = template.center
            saveStruct.theta = template.theta
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function rLott_Dollar_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            warning('off','all')
            disp('all warnings disabled')
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to rLott_Dollar (see VARARGIN)

            % Choose default command line output for rLott_Dollar
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes rLott_Dollar wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            global ghandles;
            global points;
            global pID;
            global drawing;
            global sketchID;
            global figures;
            global figCount;
            figures(1) = gca;

            figCount = 1;
            axes(figures(1));
            % global plots;
            ghandles = handles;
            tic
            axis([0 1 0 1])
            hold on
            set(gca,'XTick',[],'Ytick',[],'Box','on')
%             set(ghandles.saveButton,'Enable','off')
            set(ghandles.clearButton,'Enable','off')
            set(ghandles.classifyButton,'Enable','off')


            set(gcf,'WindowButtonDownFcn',@app.mouseDown,...
                'WindowButtonMotionFcn',@app.mouseMoving,...
                'WindowButtonUpFcn',@app.mouseUp)

            points = zeros(1000,3);
            pID = 0;
            drawing = 0;
            sketchID = 0;
            loadTemplates(app);
            clc;
        end

        % Button pushed function: classifyButton
        function classifyButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to classifyButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ghandles;
            global points;
            global templateObjects;
            global pID;
            if pID < 5
                return
            end
            drawing = drawnObj(0, points(1:pID,:));
            set(ghandles.displayText,'string','Classifying...');
            [bestTemplate, Score] = drawing.Recognize(templateObjects);
            %             drawnObj.compareToTemplates(drawing,templateObjects);
            outputClass(app, bestTemplate,real(Score));
        end

        % Button pushed function: clearButton
        function clearButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to clearButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global ghandles;
            global points;
            global pID;
            global sketchID;
            global figures
            global figCount;
            global templateObjects;
            % plots(1)
            axes(figures(1));
            cla;
            for i = 2: figCount
                clf(figures(i));
            end
            axes(figures(1));
            points = zeros(1000,3);
            pID = 0;
            set(ghandles.clearButton,'Enable','off');
            % set(ghandles.saveButton,'Enable','off');
            set(ghandles.classifyButton,'Enable','off');
            set(ghandles.displayText,'string', "Data Cleared");
            app.MakeNewTemplateSwitch.Value = 'Off';
            app.MakeNewTemplateSwitchValueChanged(event);
        end

        % Callback function
        function loadButton_Callback(app, event)
%             is currently inert
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to loadButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % LOAD FILE BY NAME: data_#
            global points;
            global pID;
            global sketchID;
            global figures;
            global ghandles;
            global templateObjects;
            %     loadTemplates()
            %     createTemplateFiles()
            % You can either open the training set, the sample set, or something else
            % although it better be points because i don't have any logic setup for
            % that yet!
            %     loadFromDataPack('sampleSet.mat');
            %
            % if(ghandles.TrainData.Value == 1)
            %     loadFromDataPack('trainset.mat');
            %
            % elseif(ghandles.samplePack.Value == 1)
            %     loadFromDataPack('sampleSet.mat');
            % else
            %     uiopen('*.mat')
            % end
            %
            % disp("File ready");

            set(ghandles.clearButton,'Enable','on')
            set(ghandles.saveButton,'Enable','on')
            set(ghandles.classifyButton,'Enable','on')
        end

        % Callback function
        function saveButton_Callback(app, event)
%             is currently inert
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to saveButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global points;
            global pID;
            global sketchID;
            global ghandles;
            global templateObjects;
            % fileNum = 1;
            if pID < 999
                points(pID+1:end,:)=[];
            end
            % groundNum = (get(ghandles.groundTruth,'string'));
            % % fileNameTry = fileName ;
            % fileNum = 0;
            % fileName = ['data_', groundNum, num2str(fileNum), '.mat'];
            %
            % while(isfile(fileName))
            %     fileNum = fileNum +1;
            %     fileName = ['data_', groundNum, num2str(fileNum), '.mat'];
            % end
            % save(fileName,'points')
            %
            % clearvars -except templateObjects;
            % txt = jsonencode(templateObjects);
            % [file] = uiputfile('digitMatrix.mat');
            % save(file,'txt');
            uisave();
            disp("file Saved");
        end

        % Callback function
        function showOptions_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to showOptions (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of showOptions
            global ghandles
            if(ghandles.showOptions.Value)
                set(ghandles.loadButton,'Enable','on')
                set(ghandles.loadButton,'Visible','on')
%                 set(ghandles.saveButton,'Enable','on')
                set(ghandles.saveButton,'Visible','on')
                set(ghandles.showOptions,'Visible','on')
                set(ghandles.groundTruth,'Visible','on')
                return
            end
            set(ghandles.loadButton,'Enable','off')
            set(ghandles.loadButton,'Visible','off')
            set(ghandles.saveButton,'Enable','off')
            set(ghandles.saveButton,'Visible','off')
            set(ghandles.showOptions,'Visible','off')
            set(ghandles.groundTruth,'Visible','off')
        end

        % Clicked callback: uipushtool1
        function uipushtool1_ClickedCallback(app, event)
            % Create a new UI figure
            uifigure('WindowStyle', app.figure1.WindowStyle);
        end

        % Value changed function: MakeNewTemplateSwitch
        function MakeNewTemplateSwitchValueChanged(app, event)
            value = app.MakeNewTemplateSwitch.Value;
            if value == "Off"
                app.overwriteBox.Visible = 'off';
                app.nameTextLabel.Visible = 'off';
                app.nameText.Visible = 'off';
                app.nameText.Enable = 'off';
                app.nameText.Value = "";
                app.confirmButton.Visible = 'off';
                app.confirmButton.Enable = 'off';
            end
            if value == "On"
                app.nameTextLabel.Visible = 'on';
                app.nameText.Visible = 'on';
                app.nameText.Enable = 'on';
                app.overwriteBox.Visible = 'on';
            end
        end

        % Callback function
        function nameTextValueChanged(app, event)

        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            clearButton_Callback(app, event)
        end

        % Menu selected function: ClassifyMenu
        function ClassifyMenuSelected(app, event)
            classifyButton_Callback(app, event)
        end

        % Menu selected function: MakeATemplateMenu
        function MakeATemplateMenuSelected(app, event)
            app.MakeNewTemplateSwitch.Value = 'On'
            app.MakeNewTemplateSwitchValueChanged(event)
        end

        % Value changing function: nameText
        function nameTextValueChanging(app, event)
            value = app.nameText.Value;
            if isempty(value)
                app.confirmButton.Enable ='on';
                app.confirmButton.Visible ='on';
            end

        end

        % Value changed function: nameText
        function nameTextValueChanged2(app, event)
            value = app.nameText.Value;
            if value ~= ""
                app.confirmButton.Enable ='on';
                app.confirmButton.Visible ='on';
            else
                app.confirmButton.Enable ='off';
            end
        end

        % Button pushed function: confirmButton
        function confirmButtonPushed(app, event)
%             Creates a new template from scratch using your points
            value = app.nameText.Value;
            if ~isempty(value) && length(value) > 0
                createNewTemplate(app, value);
                app.nameText.Value = "";
            end
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
% resets the app environment sort of like a super-duper clear
            global templateObjects;
            global ghandles;
            global points;
            global pID;
            global drawing;
            global sketchID;
            global figures;
            global figCount;
            ClearMenuSelected(app,event)
            figures(1) = gca;

            figCount = 1;
            axes(figures(1));
            % global plots;
            tic
            axis([0 1 0 1])
            hold on
            set(gca,'XTick',[],'Ytick',[],'Box','on')
            set(ghandles.saveButton,'Enable','off')
            set(ghandles.clearButton,'Enable','off')
            set(ghandles.classifyButton,'Enable','off')
            points = zeros(1000,3);
            pID = 0;
            drawing = 0;
            sketchID = 0;
            loadTemplates(app);
            clc;
        end

        % Value changed function: ClassesListBox
        function ClassesListBoxValueChanged(app, event)
% when you select an item in the list, it'll; go here and check if either
% A. the object already exists and we have no reason to create a new one or
% B. the object does not exist and a new image must be created. I could not
% find another way around this at the given time
            global figures;
            global templateObjects;
            value = app.ClassesListBox.Value;
            fileName = [value,'.png'];
            if(isfile(fileName))
                app.examplePlot.ImageSource = fileName;
                return
            end
            for i = 1: length(templateObjects)
                template = templateObjects(i)
                if strcmpi(template.groundTruth, value)
                    break;
                end
            end
%             create image from template
            y = template.Y;
            x = template.X;
            imageSize= 32;
%             normalize the image by adjusting the minimum values to 0 
            minCorner = [min(x), min(y)];
            x = x + minCorner(1)*-1;
            y = y + minCorner(2)*-1;
%             then by dividing by the maximum corner values. 
            maxCorner = [max(x), max(y)];
            x = x./maxCorner(1);
            y = y./maxCorner(2);
%             and then upscaling the integers from a 0 to imageSize value
            x = round(x*(imageSize-1)) + 1;
            y = round(y*(imageSize-1)) + 1;
            center = [round(mean(x)),round(mean(y))];

%             instantiate the image layers
            blue = zeros(imageSize);
            red = blue;
            green = blue;
%             First, color the blue layer with the line
            for i = 1:length(x)
                blue(x(i),y(i)) = 1;
            end
            % then color the end, and start points respectively
            red(x(end),y(end)) = 1;
            green(x(1),y(1)) = 1;
% finally, add the center points, and place inside the image for writing. 
            red(center(1),center(2)) = 1;
            blue(center(1),center(2)) = 1;
            green(center(1),center(2)) = 1;
            imageHolder = zeros(imageSize,imageSize,3);
            imageHolder(:,:,1) = red;
            imageHolder(:,:,2) = green;
            imageHolder(:,:,3) = blue;
            imwrite(imageHolder,fileName)

            app.examplePlot.ImageSource = fileName;


        end

        % Menu selected function: DeleteItemMenu
        function DeleteItemMenuSelected(app, event)
%             When you right click an item in the list, you can potentially
%             delete it!
            global templateObjects
            value = app.ClassesListBox.Value;
%             if the item is empty, you didn't really select anything
            if isempty(value)
                return
            end
            question = ["Are you sure you want to delete ",value];
            answer = questdlg(question,'Confirmation For Delete','Yes','No');
            objLen = length(templateObjects);
            if objLen < 3
                msgbox(['You cannot delete any more';'without causing the program to break'],'WARNING',"error")
            end
            %             Make sure the user knows they'll be dleeting things, if
            %             they're fine with it then continue.
            if strcmp(answer,'Yes')
                dataIDs  = loadTemplateDict(app);
                for i = 1:objLen
                    template =templateObjects(i);
                    if (strcmp(template.groundTruth,value))
% checks position of the point being removed so i don't accidentally remove
% the wrong thing
                        if (i == 1)
                            dataIDs = dataIDs(2:end);
                            templateObjects = templateObjects(2:end);
                        elseif(i == objLen)
                            dataIDs = dataIDs(1:end-1);
                            templateObjects = templateObjects(1:end-1);
                        else
                            dataIDs = dataIDs([1:i-1,i+1:objLen]);
                            templateObjects = templateObjects([1:i-1,i+1:objLen]) ;
                        end
%                        for some reason this has been repeatedly needed,
%                        it feels excessive though.
                        filename = strjoin(['template_',string(value),'.mat']);
                        filename = erase(filename," ");
                        delete(filename);

                        pictureFilename = erase(strjoin([string(value),'.png']),' ');
                        if exist(pictureFilename,'file')
                            delete(pictureFilename);
                        end
                        writecell(dataIDs,'templateDict.csv');
                        app.ClassesListBox.Items = string(dataIDs');
                        return
                    end

                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'rLott_Dollar';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'New Figure';
            app.uipushtool1.ClickedCallback = createCallbackFcn(app, @uipushtool1_ClickedCallback, true);
            app.uipushtool1.Icon = 'uipushtool1_image.png';
            app.uipushtool2 = uipushtool(app.uitoolbar1);
            app.uipushtool2.Tag = 'uipushtool2';
            app.uipushtool2.Tooltip = 'Save Figure';
            app.uipushtool2.Icon = 'uipushtool2_image.png';
            app.drawGraph = uiaxes(app.figure1);
            app.drawGraph.Tag = 'drawGraph';
            app.clearButton = uibutton(app.figure1, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButton_Callback, true);
            app.clearButton.Tag = 'clearButton';
            app.clearButton.Text = 'Clear';
            app.classifyButton = uibutton(app.figure1, 'push');
            app.classifyButton.ButtonPushedFcn = createCallbackFcn(app, @classifyButton_Callback, true);
            app.classifyButton.Tag = 'classifyButton';
            app.classifyButton.Text = 'Classify';
            app.MakeNewTemplateSwitchLabel = uilabel(app.figure1);
            app.MakeNewTemplateSwitchLabel.Text = 'Make New Template';
            app.MakeNewTemplateSwitch = uiswitch(app.figure1, 'slider');
            app.MakeNewTemplateSwitch.ValueChangedFcn = createCallbackFcn(app, @MakeNewTemplateSwitchValueChanged, true);
            app.MakeNewTemplateSwitch.Tag = 'newButton';
            app.confirmButton = uibutton(app.figure1, 'push');
            app.confirmButton.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirmButton.Tag = 'confirmButton';
            app.confirmButton.Enable = 'off';
            app.confirmButton.Visible = 'off';
            app.confirmButton.Text = 'Click here when you''re ready to make the template';
            app.nameTextLabel = uilabel(app.figure1);
            app.nameTextLabel.Visible = 'off';
            app.nameTextLabel.Text = 'What Is This Shape Called?';
            app.nameText = uieditfield(app.figure1, 'text');
            app.nameText.ValueChangedFcn = createCallbackFcn(app, @nameTextValueChanged2, true);
            app.nameText.ValueChangingFcn = createCallbackFcn(app, @nameTextValueChanging, true);
            app.nameText.Enable = 'off';
            app.nameText.Visible = 'off';
            app.overwriteBox = uicheckbox(app.figure1);
            app.overwriteBox.Visible = 'off';
            app.overwriteBox.Text = 'Overwrite Old Template';
            app.overwriteBox.Value = true;
            app.ClassesListBoxLabel = uilabel(app.figure1);
            app.ClassesListBoxLabel.Text = 'Classes';
            app.ClassesListBox = uilistbox(app.figure1);
            app.ClassesListBox.Items = {};
            app.ClassesListBox.ValueChangedFcn = createCallbackFcn(app, @ClassesListBoxValueChanged, true);
            app.ClassesListBox.Value = {};
            app.displayText = uitextarea(app.figure1);
            app.displayText.Tag = 'displayText';
            app.displayText.Editable = 'off';
            app.displayText.Value = {'See What You''re Drawing'};
            app.examplePlot = uiimage(app.figure1);
            app.examplePlot.Tag = 'examplePlot';
            app.SelectclasstodisplayonrightLabel = uilabel(app.figure1);
            app.SelectclasstodisplayonrightLabel.Text = 'Select class to display on right. ';
            app.Label = uilabel(app.figure1);
            app.Label.Text = '';
            app.GreenStartPointLabel = uilabel(app.figure1);
            app.GreenStartPointLabel.Text = 'Green = Start Point';
            app.RedEndPointLabel = uilabel(app.figure1);
            app.RedEndPointLabel.Text = 'Red = End Point';
            app.WhiteCenterLabel = uilabel(app.figure1);
            app.WhiteCenterLabel.Text = 'White = Center';
            app.ContextMenu2 = uicontextmenu(app.figure1);
            app.ClearMenu = uimenu(app.ContextMenu2);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.ClassifyMenu = uimenu(app.ContextMenu2);
            app.ClassifyMenu.MenuSelectedFcn = createCallbackFcn(app, @ClassifyMenuSelected, true);
            app.ClassifyMenu.Text = 'Classify';
            app.MakeATemplateMenu = uimenu(app.ContextMenu2);
            app.MakeATemplateMenu.MenuSelectedFcn = createCallbackFcn(app, @MakeATemplateMenuSelected, true);
            app.MakeATemplateMenu.Text = 'Make New Template';
            app.ResetMenu = uimenu(app.ContextMenu2);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            % Assign app.ContextMenu2
            app.drawGraph.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.figure1);
            app.DeleteItemMenu = uimenu(app.ContextMenu3);
            app.DeleteItemMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteItemMenuSelected, true);
            app.DeleteItemMenu.Text = 'Delete Item';
            % Assign app.ContextMenu3
            app.ClassesListBox.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rLott_Dollar_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)rLott_Dollar_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end